classdef Diffuser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiffuserGUI                     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ImportdataTab                   matlab.ui.container.Tab
        text11                          matlab.ui.control.Label
        RandomButton                    matlab.ui.control.Button
        ClipboardButton                 matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        CleartableButton                matlab.ui.control.Button
        SelectfileButton                matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        DeconvolutionoptionalTab        matlab.ui.container.Tab
        DeconvolutionismodifiedfromLabel  matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        BeamsizeunitDropDown            matlab.ui.control.DropDown
        BeamsizeunitDropDownLabel       matlab.ui.control.Label
        FWHMLorentzEditField            matlab.ui.control.NumericEditField
        FWHMLorentzEditFieldLabel       matlab.ui.control.Label
        fullwidthathalfmaximiumLabel    matlab.ui.control.Label
        FWHMGaussEditField              matlab.ui.control.NumericEditField
        FWHMGaussEditFieldLabel         matlab.ui.control.Label
        FWHMEditField                   matlab.ui.control.NumericEditField
        FWHMEditFieldLabel              matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        WidthEditFieldLabel             matlab.ui.control.Label
        DiameterEditField               matlab.ui.control.NumericEditField
        DiameterEditFieldLabel          matlab.ui.control.Label
        DeconvoluteanalyticalbeameffectsCheckBox  matlab.ui.control.CheckBox
        BeamButtonGroup                 matlab.ui.container.ButtonGroup
        VoigtButton                     matlab.ui.control.ToggleButton
        LorentzButton                   matlab.ui.control.ToggleButton
        GaussButton                     matlab.ui.control.ToggleButton
        RectangleButton                 matlab.ui.control.ToggleButton
        CircularButton                  matlab.ui.control.ToggleButton
        DiffusionmodelingTab            matlab.ui.container.Tab
        XunitDropDown                   matlab.ui.control.DropDown
        XunitDropDownLabel              matlab.ui.control.Label
        CalculateDtButton               matlab.ui.control.Button
        StartModelingButton             matlab.ui.control.Button
        InitialconcentrationsoptionalPanel  matlab.ui.container.Panel
        SeeC1C2andC3definitionsButton   matlab.ui.control.Button
        steplengthEditField             matlab.ui.control.NumericEditField
        steplengthEditFieldLabel        matlab.ui.control.Label
        InputtheCrangeoftheinitialflatpeaktroughinprofileILLabel  matlab.ui.control.Label
        maxEditField                    matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        minEditField                    matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        FixedC3CheckBox                 matlab.ui.control.CheckBox
        FixedC2CheckBox                 matlab.ui.control.CheckBox
        C3EditField                     matlab.ui.control.NumericEditField
        C2EditField                     matlab.ui.control.NumericEditField
        C1EditField                     matlab.ui.control.NumericEditField
        AflatpeaktroughcanbeseeninprofileILCheckBox  matlab.ui.control.CheckBox
        FixedC1CheckBox                 matlab.ui.control.CheckBox
        mediumCusedinprofileKLLabel     matlab.ui.control.Label
        maxCLabel                       matlab.ui.control.Label
        minCLabel                       matlab.ui.control.Label
        DiffusionprofileButtonGroup     matlab.ui.container.ButtonGroup
        LButton                         matlab.ui.control.ToggleButton
        KButton                         matlab.ui.control.ToggleButton
        JButton                         matlab.ui.control.ToggleButton
        IButton                         matlab.ui.control.ToggleButton
        HButton                         matlab.ui.control.ToggleButton
        GButton                         matlab.ui.control.ToggleButton
        FButton                         matlab.ui.control.ToggleButton
        EButton                         matlab.ui.control.ToggleButton
        DButton                         matlab.ui.control.ToggleButton
        CButton                         matlab.ui.control.ToggleButton
        BButton                         matlab.ui.control.ToggleButton
        AButton                         matlab.ui.control.ToggleButton
        TimecalculationTab              matlab.ui.container.Tab
        InitialtemperatureT0EditField   matlab.ui.control.NumericEditField
        InitialtemperatureT0EditFieldLabel  matlab.ui.control.Label
        TrialsforMonteCarloEditField    matlab.ui.control.NumericEditField
        TrialsforMonteCarloEditFieldLabel  matlab.ui.control.Label
        TemperatureerrorEditField       matlab.ui.control.NumericEditField
        TemperatureerrorEditFieldLabel  matlab.ui.control.Label
        DiffusionCoefficientDPanel      matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        UseaconstantDButton             matlab.ui.control.RadioButton
        UseArrheniusequationButton      matlab.ui.control.RadioButton
        UITable2                        matlab.ui.control.Table
        ExamplesDropDown                matlab.ui.control.DropDown
        ExamplesDropDownLabel           matlab.ui.control.Label
        CoolingpathPanel                matlab.ui.container.Panel
        UITable3                        matlab.ui.control.Table
        OptionsDropDown                 matlab.ui.control.DropDown
        OptionsDropDownLabel            matlab.ui.control.Label
        CalculatetButton                matlab.ui.control.Button
        KLabel                          matlab.ui.control.Label
        whencoolingLabel                matlab.ui.control.Label
        sigmaLabel                      matlab.ui.control.Label
        DcalculationTab                 matlab.ui.container.Tab
        UITable4                        matlab.ui.control.Table
        DcalculationforexperimentalstudyPanel  matlab.ui.container.Panel
        CalculateDButton                matlab.ui.control.Button
        TimeEditField                   matlab.ui.control.NumericEditField
        TimeEditFieldLabel              matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
    end


    properties (Access = private)
        count
        data
        name
        DiffCoef
        outputname
        output
    end

    methods (Access = private)

        function Readclipdata(app)
            %read clipboard data
            app.data=getclipdata;
            col=size(app.data,2);
            if col~=2 && col~=3
                uialert(app.DiffuserGUI,'Input data should have 2 or 3 columns','')
                return
            end
            app.UITable.Data=app.data;
            app.UITable.ColumnFormat={'numeric'};

            x = app.data(:,1);
            C = app.data(:,2);
            [~,I]=sort(x);
            app.data=app.data(I,:);

            if col==3
                err = app.data(:,3);
                h=errorbar(app.UIAxes, x, C, err);
                h.Marker='.';
                h.MarkerSize=12;
                h.LineStyle='none';
                h.CapSize=0;
            else
                plot(app.UIAxes, x, C, '-o');
            end
        end

        function [name, data] = readDiffCoef(app)
            str='DiffusionCoefficient.xlsx';
            if exist(str,'file')
                sheets = sheetnames(str);
                cs=length(sheets);
                if cs==1
                    sheet=sheets{1};
                    v=1;
                else
                    [st,v]=listdlg('PromptString','Select a sheet','SelectionMode','single','ListString',sheets);
                    sheet=sheets{st};
                end
                if v
                    raw=readcell(str,'Sheet',sheet);
                else
                    name=[]; data=[];
                    return
                end
            else
                uialert(app.DiffuserGUI, "'DiffusionCoefficient.xlsx' does not exist, please input diffusion coefficients manually.",'')
                name=[]; data=[];
                return
            end
            name=join(raw(2:end,1:2));
            data=raw(2:end,3:4);
            index=cellfun(@(x) isa(x,'missing'), data);
            data(index) = {[]};
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.count=1;
            app.UITable.Data=repmat({[]},10,3);
            app.UITable.ColumnFormat={'numeric'};
            [app.name, app.DiffCoef] = readDiffCoef(app);
            if ~isempty(app.DiffCoef)
                app.ExamplesDropDown.Items=app.name;
                value = app.ExamplesDropDown.Value;
                site=find(strcmp(app.name,value));
                app.UITable2.Data=app.DiffCoef(site,:);
                app.UITable2.ColumnFormat={'numeric'};
            end
            app.UITable3.Data={0,'K/myr','T=T0'};
            app.UITable3.ColumnFormat={'numeric',{'K/myr','K/kyr','K/yr','K/sec'},'char'};
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [filename, pathname]=uigetfile({'*.xlsx;*.xls;*.csv;*.txt'},'Select a excel','MultiSelect','off');
            if ~pathname
                return
            end
            str=fullfile(pathname,filename);
            [~,~,extension]=fileparts(filename);
            switch extension
                case {'.txt','.csv'}
                    tmp=readmatrix(str);
                    if size(tmp,2)>2
                        prompt={'Distance column (A,B,C etc)','Concentration column','Uncertainty column (optional)'};
                        dlgtitle='Input';
                        dims=[1 35];
                        definput={'A','B','C'};
                        answer=inputdlg(prompt,dlgtitle,dims,definput);
                        data0(:,1)=readmatrix(str,'Range',strcat(answer{1},':',answer{1}));
                        data0(:,2)=readmatrix(str,'Range',strcat(answer{2},':',answer{2}));
                        if ~isempty(answer{3})
                            data0(:,3)=readmatrix(str,'Range',strcat(answer{3},':',answer{3}));
                        end
                        app.data=data0;
                    else
                        app.data=tmp;
                    end
                case {'.xlsx','.xls'}
                    sheets = sheetnames(str);
                    cs=length(sheets);
                    if cs==1
                        sheet=sheets{1};
                        v=1;
                    else
                        [st,v]=listdlg('PromptString','Select a sheet','SelectionMode','single','ListString',sheets);
                        sheet=sheets{st};
                    end
                    if v
                        tmp=readmatrix(str,'Sheet',sheet);
                        if size(tmp,2)>2
                            prompt={'Distance column (A,B,C etc)','Concentration column','Uncertainty column (optional)'};
                            dlgtitle='Input';
                            dims=[1 35];
                            definput={'A','B','C'};
                            answer=inputdlg(prompt,dlgtitle,dims,definput);
                            data0(:,1)=readmatrix(str,'Sheet',sheet,'Range',strcat(answer{1},':',answer{1}));
                            data0(:,2)=readmatrix(str,'Sheet',sheet,'Range',strcat(answer{2},':',answer{2}));
                            if ~isempty(answer{3})
                                data0(:,3)=readmatrix(str,'Sheet',sheet,'Range',strcat(answer{3},':',answer{3}));
                            end
                            app.data=data0;
                        else
                            app.data=tmp;
                        end
                    end
                otherwise
                    return
            end

            % delete the first non-numeric rows
            for i=1:size(app.data,1)
                if  isnan(app.data(1,:))
                    app.data(1,:)=[];
                else
                    break
                end
            end

            if size(app.data,2)==3
                app.UITable.Data=app.data;
                h=errorbar(app.UIAxes, app.data(:,1), app.data(:,2), app.data(:,3));
                h.Marker='.';
                h.MarkerSize=12;
                h.LineStyle='none';
                h.CapSize=0;
            elseif size(app.data,2)==2
                app.UITable.Data=app.data;
                plot(app.UIAxes, app.data(:,1), app.data(:,2), '-o');
            else
                uialert(app.DiffuserGUI,'Input data should have 2 or 3 columns','')
                return
            end
            app.UITable.ColumnFormat={'numeric'};

            [~,I]=sort(app.data(:,1));
            app.data=app.data(I,:);
        end

        % Button pushed function: ClipboardButton
        function ClipboardButtonPushed(app, event)
            Readclipdata(app);
        end

        % Key press function: UITable
        function UITableKeyPress(app, event)
            key = event.Key;
            modifier = event.Modifier;
            if strcmp(key,'v') && ismember('control',modifier)
                Readclipdata(app);
            end
        end

        % Button pushed function: RandomButton
        function RandomButtonPushed(app, event)
            C1=30; C2=100; C3=70;
            Dt=1e-10;
            h=20*1e-6;
            switch mod(app.count,12)
                case 1
                    x=(0:3:60)'*1e-6;
                    C=(C2-C1)*erf(x/2/sqrt(Dt))+C1;
                    x=x+15e-6;
                case 2
                    x=(-60:3:0)'*1e-6;
                    C=(C2-C1)*erf(-x/2/sqrt(Dt))+C1;
                    x=x+75e-6;
                case 3
                    x=(0:3:60)'*1e-6;
                    C=(C2-C1)*erfc(x/2/sqrt(Dt))+C1;
                    x=x+15e-6;
                case 4
                    x=(-60:3:0)'*1e-6;
                    C=(C2-C1)*erfc(-x/2/sqrt(Dt))+C1;
                    x=x+75e-6;
                case 5
                    h=60*1e-6;
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)*(erf((h+x)/2/sqrt(Dt))+erf((h-x)/2/sqrt(Dt))-1)+C1;
                    x=x+75e-6;
                case 6
                    h=60*1e-6;
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)*(erfc((h+x)/2/sqrt(Dt))+erfc((h-x)/2/sqrt(Dt)))+C1;
                    x=x+75e-6;
                case 7
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)/2*(1+erf((x)/2/sqrt(Dt)))+C1;
                    x=x+75e-6;
                case 8
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)/2*erfc((x)/2/sqrt(Dt))+C1;
                    x=x+75e-6;
                case 9
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)/2*(erf((h-x)/2/sqrt(Dt))+erf((h+x)/2/sqrt(Dt)))+C1;
                    x=x+75e-6;
                case 10
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)/2*(erfc((h-x)/2/sqrt(Dt))+erfc((h+x)/2/sqrt(Dt)))+C1;
                    x=x+75e-6;
                case 11
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C3)/2*erf((h-x)/2/sqrt(Dt))+(C2-C1)/2*erf((h+x)/2/sqrt(Dt))+(C1+C3)/2;
                    x=x+75e-6;
                case 0
                    x=(-60:3:60)'*1e-6;
                    C=(C3-C1)/2*erfc((h-x)/2/sqrt(Dt))+(C2-C1)/2*erfc((h+x)/2/sqrt(Dt))+C1;
                    x=x+75e-6;
            end
            temp=rand(length(x),1)*2-1; %[-1,1]
            C=C+temp*3;
            app.data=[x,C];
            app.UITable.Data=app.data;
            app.XunitDropDown.Value='m';
            plot(app.UIAxes, x, C, '-o');
            app.count=app.count+1;
        end

        % Button pushed function: CleartableButton
        function CleartableButtonPushed(app, event)
            app.UITable.Data=repmat({[]},10,3);
            cla(app.UIAxes)
        end

        % Selection changed function: DiffusionprofileButtonGroup
        function DiffusionprofileButtonGroupSelectionChanged(app, event)
            selectedButton = app.DiffusionprofileButtonGroup.SelectedObject;
            profiletype = selectedButton.Text;
            if contains(profiletype, {'I','J'})
                app.AflatpeaktroughcanbeseeninprofileILCheckBox.Enable='on';
                app.FixedC1CheckBox.Enable='on';
                app.C1EditField.Enable='on';
                app.FixedC2CheckBox.Enable='on';
                app.C2EditField.Enable='on';
                app.FixedC3CheckBox.Value=0;
                app.FixedC3CheckBox.Enable='off';
                app.C3EditField.Enable='off';
            elseif contains(profiletype, {'K','L'})
                app.AflatpeaktroughcanbeseeninprofileILCheckBox.Enable='on';
                app.FixedC1CheckBox.Enable='on';
                app.C1EditField.Enable='on';
                app.FixedC2CheckBox.Enable='on';
                app.C2EditField.Enable='on';
                app.FixedC3CheckBox.Enable='on';
                app.C3EditField.Enable='on';
            else
                app.AflatpeaktroughcanbeseeninprofileILCheckBox.Enable='off';
                app.FixedC1CheckBox.Enable='on';
                app.C1EditField.Enable='on';
                app.FixedC2CheckBox.Enable='on';
                app.C2EditField.Enable='on';
                app.FixedC3CheckBox.Value=0;
                app.FixedC3CheckBox.Enable='off';
                app.C3EditField.Enable='off';
            end
            app.AflatpeaktroughcanbeseeninprofileILCheckBox.Value=1;
            app.InputtheCrangeoftheinitialflatpeaktroughinprofileILLabel.Enable='off';
            app.minEditField.Enable='off';
            app.minEditFieldLabel.Enable='off';
            app.maxEditField.Enable='off';
            app.maxEditFieldLabel.Enable='off';
            app.steplengthEditField.Enable='off';
            app.steplengthEditFieldLabel.Enable='off';
        end

        % Button pushed function: StartModelingButton
        function StartModelingButtonPushed(app, event)
            global log10Dt elog10Dt
            %prepare diffusion data-----------------------------------------
            if isempty(app.data)
                return
            end
            selectedButton = app.DiffusionprofileButtonGroup.SelectedObject;
            profiletype = selectedButton.Text;
            xunit=app.XunitDropDown.Value;
            col=size(app.data,2);
            x = app.data(:,1);
            C = app.data(:,2);
            if col==3
                err=app.data(:,3);
            else
                err=ones(length(x),1);
            end
            weights=1./(err.^2);
            switch xunit
                case 'nm'
                    factor=1e9;
                case 'um'
                    factor=1e6;
                case 'mm'
                    factor=1e3;
                case 'm'
                    factor=1;
            end
            x=x/factor;
            %prepare deconvolution-----------------------------------------
            deconvolution=app.DeconvoluteanalyticalbeameffectsCheckBox.Value;
            if deconvolution
                beamunit=app.BeamsizeunitDropDown.Value;
                switch beamunit
                    case 'nm'
                        f=1e9;
                    case 'um'
                        f=1e6;
                    case 'mm'
                        f=1e3;
                    case 'm'
                        f=1;
                end
                try
                    if app.CircularButton.Value
                        width=app.DiameterEditField.Value/f; %circular, elliptical beam
                        dx=min([width/4 ((max(x))-min(x))/(max([25 (max(size(x)))])*4)]);
                        model_x=min(x)-(max(x)-min(x))/2:dx:max(x)+(max(x)-min(x))/2;
                        [X,Y] = meshgrid(0:dx:width,0:dx:width);
                        centre=[mean(mean(X,1)) mean(mean(Y,2))];
                        dist=sqrt(abs(X-centre(1)).^2+abs(Y-centre(2)).^2);
                        dist(dist>width/2)=NaN;
                        dist(~isnan(dist))=1;
                        dist(isnan(dist))=0;
                        proportions=sum(dist)./(sum(sum(dist)));
                        proportions=[zeros(1,floor((size(model_x,2)-size(proportions,2))/2)) proportions zeros(1,ceil((size(model_x,2)-size(proportions,2))/2))];
                        props_matrix=zeros(size(proportions,2)+400,size(model_x,2));
                        for k=1:size(props_matrix,2)
                            props_matrix(:,(k))=circshift([zeros(1,200) proportions zeros(1,200)]',(k-round(size(props_matrix,2)/2)));
                        end
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        pmsum=1./(sum(props_matrix));
                    end
                    if app.RectangleButton.Value
                        width=app.WidthEditField.Value/f; %rectangle, square beam
                        dx=min([width/4 ((max(x))-min(x))/(max([25 (max(size(x)))])*4)]);
                        model_x=min(x)-(max(x)-min(x))/10:dx:max(x)+(max(x)-min(x))/10;
                        n_conv=round(width/mean(diff(model_x)));
                        proportions=ones(1,n_conv);
                        proportions=proportions./sum(proportions);
                        proportions=[zeros(1,floor((size(model_x,2)-size(proportions,2))/2)) proportions zeros(1,ceil((size(model_x,2)-size(proportions,2))/2))];
                        props_matrix=zeros(size(proportions,2)+400,size(model_x,2));
                        for k=1:size(props_matrix,2)
                            props_matrix(:,(k))=circshift([zeros(1,200) proportions zeros(1,200)]',(k-round(size(props_matrix,2)/2)));
                        end
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        pmsum=1./(sum(props_matrix));
                    end
                    if app.GaussButton.Value
                        FWHM=app.FWHMEditField.Value/f; %full width at half maximum; Gaussian beam
                        dx=min([FWHM/4 ((max(x))-min(x))/(max([25 (max(size(x)))])*4)]);
                        model_x=min(x)-(max(x)-min(x))/10:dx:max(x)+(max(x)-min(x))/10;
                        sigma=FWHM/2.355;
                        proportions=exp(-((model_x-mean(model_x)).^2./(2.*sigma.^2)));
                        proportions=proportions./sum(proportions);
                        proportions=[zeros(1,floor((size(model_x,2)-size(proportions,2))/2)) proportions zeros(1,ceil((size(model_x,2)-size(proportions,2))/2))];
                        props_matrix=zeros(size(proportions,2)+400,size(model_x,2));
                        for k=1:size(props_matrix,2)
                            props_matrix(:,(k))=circshift([zeros(1,200) proportions zeros(1,200)]',(k-round(size(props_matrix,2)/2)));
                        end
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        pmsum=1./(sum(props_matrix));
                    end
                    if app.LorentzButton.Value
                        FWHM=app.FWHMEditField.Value/f; %full width at half maximum; Lorentzian beam
                        dx=min([FWHM/4 ((max(x))-min(x))/(max([25 (max(size(x)))])*4)]);
                        model_x=min(x)-(max(x)-min(x))/10:dx:max(x)+(max(x)-min(x))/10;
                        proportions=(1./pi).*((0.5.*FWHM)./((model_x-mean(model_x)).^2+(0.5.*FWHM).^2));
                        proportions=proportions./sum(proportions);
                        proportions=[zeros(1,floor((size(model_x,2)-size(proportions,2))/2)) proportions zeros(1,ceil((size(model_x,2)-size(proportions,2))/2))];
                        props_matrix=zeros(size(proportions,2)+400,size(model_x,2));
                        for k=1:size(props_matrix,2)
                            props_matrix(:,(k))=circshift([zeros(1,200) proportions zeros(1,200)]',(k-round(size(props_matrix,2)/2)));
                        end
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        pmsum=1./(sum(props_matrix));
                    end
                    if app.VoigtButton.Value
                        FWHM_L=app.FWHMLorentzEditField.Value/f; FWHM_G=app.FWHMGaussEditField.Value/f; %full width at half maximum; Voigt beam
                        FWHM=(0.5346*FWHM_L+sqrt(0.2166*FWHM_L^2+FWHM_G^2)); %get FWHM effective for voigt
                        dx=min([FWHM/4 ((max(x))-min(x))/(max([25 (max(size(x)))])*4)]);
                        model_x=min(x)-(max(x)-min(x))/10:dx:max(x)+(max(x)-min(x))/10;
                        proportions=((1.36603*(FWHM_L/(0.5346*FWHM_L+sqrt(0.2166*FWHM_L^2+FWHM_G^2)))-0.47719*(FWHM_L/(0.5346*FWHM_L+sqrt(0.2166*FWHM_L^2+FWHM_G^2)))^2+0.11116*(FWHM_L/(0.5346*FWHM_L+sqrt(0.2166*FWHM_L^2+FWHM_G^2)))^3)*((1./(pi*(0.5.*(FWHM_L+1e-10)))).*(((0.5.*(FWHM_L+1e-10))^2)./((model_x-mean(model_x)).^2+(0.5.*(FWHM_L+1e-10)).^2)))+(1-(1.36603*(FWHM_L/(0.5346*FWHM_L+sqrt(0.2166*FWHM_L^2+FWHM_G^2)))-0.47719*(FWHM_L/(0.5346*FWHM_L+sqrt(0.2166*FWHM_L^2+FWHM_G^2)))^2+0.11116*(FWHM_L/(0.5346*FWHM_L+sqrt(0.2166*FWHM_L^2+FWHM_G^2)))^3))*((1/(((FWHM_G+1e-10)/2.355)*sqrt(2*pi)))*exp(-0.5.*((model_x-mean(model_x))./((FWHM_G+1e-10)/2.355)).^2)));
                        proportions=proportions./sum(proportions);
                        proportions=[zeros(1,floor((size(model_x,2)-size(proportions,2))/2)) proportions zeros(1,ceil((size(model_x,2)-size(proportions,2))/2))];
                        props_matrix=zeros(size(proportions,2)+400,size(model_x,2));
                        for k=1:size(props_matrix,2)
                            props_matrix(:,(k))=circshift([zeros(1,200) proportions zeros(1,200)]',(k-round(size(props_matrix,2)/2)));
                        end
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        props_matrix(1:200,:)=[];
                        props_matrix=flipud(props_matrix);
                        pmsum=1./(sum(props_matrix));
                    end
                catch
                    uialert(app.DiffuserGUI,'Please check the unit of beam size and distance','');
                    return
                end
            else
                model_x=[];
                props_matrix=[];
                pmsum=[];
            end

            %initial concentration-----------------------------------------
            fixC1=app.FixedC1CheckBox.Value;
            fixC2=app.FixedC2CheckBox.Value;
            fixC3=app.FixedC3CheckBox.Value;
            if fixC1
                C1 = app.C1EditField.Value;
            else
                C1=[];
            end
            if fixC2
                C2 = app.C2EditField.Value;
            else
                C2=[];
            end
            if fixC3
                C3 = app.C3EditField.Value;
            else
                C3=[];
            end

            %             try

            %without deconvolution-----------------------------------------
            if contains(profiletype, {'I','J','K','L'})
                [log10Dt, elog10Dt, paras] = solution_IL(x, C, C1, C2, C3, profiletype, weights, factor, app, model_x, props_matrix, pmsum);
            else
                [log10Dt, elog10Dt, paras] = solution_AH(x, C, C1, C2, profiletype, weights, factor, app, model_x, props_matrix, pmsum);
            end
            %deconvolution-------------------------------------------------

            if isnan(log10Dt)
                msgbox('No fitting results can be found, please check input parameters')
                return
            end

            %             catch exc
            %                 errorMsg=exc.message;
            %                 uialert(app.Diffuser,errorMsg,'');
            %             end

            %--------------------------------------------------------------
            N=1:1:length(log10Dt); N=N';
            if contains(profiletype, {'E','F'})
                %paras=R2; C1; C2; x0; log10(Dt); h
                app.output=[N, paras(:,[1:4,6]), log10Dt, elog10Dt];
                app.outputname={'Trials', 'R2', 'C1', 'C2', 'x0', 'rim-to-core length', 'log10(Dt)', '2s'};
            elseif contains(profiletype, {'I','J'})
                %paras=R2; C1; C2; x0; log10(Dt); h
                app.output=[N, paras(:,[1:4,6]), log10Dt, elog10Dt];
                app.outputname={'Trials', 'R2', 'C1', 'C2', 'x0', 'half band width', 'log10(Dt)', '2s'};
            elseif contains(profiletype, {'K','L'})
                %paras=R2; C1; C2; x0; log10(Dt); h; C3
                app.output=[N, paras(:,[1:3,7,4,6]), log10Dt, elog10Dt];
                app.outputname={'Trials', 'R2', 'C1', 'C2', 'C3', 'x0', 'half band width', 'log10(Dt)', '2s'};
            else
                %paras=R2; C1; C2; x0; log10(Dt)
                app.output=[N, paras(:,1:4), log10Dt, elog10Dt];
                app.outputname={'Trials', 'R2', 'C1', 'C2', 'x0', 'log10(Dt)', '2s'};
            end
        end

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(app, event)
            coolingpath = app.OptionsDropDown.Value;
            switch coolingpath
                case 'isothermal'
                    app.UITable3.Data={0,'K/myr','T=T0'};
                    app.UITable3.ColumnEditable=[false false false];
                    app.UITable3.ColumnFormat={'numeric',{'K/myr','K/kyr','K/yr','K/sec'},'char'};
                    app.TrialsforMonteCarloEditField.Enable='off';
                    app.TrialsforMonteCarloEditFieldLabel.Enable='off';
                case 'linear cooling'
                    app.UITable3.Data={0,'K/myr','T=T0-At'};
                    app.UITable3.ColumnEditable=[true true false];
                    app.UITable3.ColumnFormat={'numeric',{'K/myr','K/kyr','K/yr','K/sec'},'char'};
                    app.TrialsforMonteCarloEditField.Enable='on';
                    app.TrialsforMonteCarloEditFieldLabel.Enable='on';
                case 'exponential cooling'
                    app.UITable3.Data={0,'K/myr','T=T0*exp(-At)'};
                    app.UITable3.ColumnEditable=[true true false];
                    app.UITable3.ColumnFormat={'numeric',{'K/myr','K/kyr','K/yr','K/sec'},'char'};
                    app.TrialsforMonteCarloEditField.Enable='on';
                    app.TrialsforMonteCarloEditFieldLabel.Enable='on';
                case 'parabolic cooling'
                    app.UITable3.Data={0,'K/myr^2','T=T0-At^2'};
                    app.UITable3.ColumnEditable=[true true false];
                    app.UITable3.ColumnFormat={'numeric',{'K/myr^2','K/kyr^2','K/yr^2','K/sec^2'},'char'};
                    app.TrialsforMonteCarloEditField.Enable='on';
                    app.TrialsforMonteCarloEditFieldLabel.Enable='on';
            end
        end

        % Value changed function: ExamplesDropDown
        function ExamplesDropDownValueChanged(app, event)
            value = app.ExamplesDropDown.Value;
            site=find(strcmp(app.name,value));
            app.UITable2.Data=app.DiffCoef(site,:);
        end

        % Button pushed function: CalculateDtButton
        function CalculateDtButtonPushed(app, event)
            global log10Dt elog10Dt
            %elog10Dt is 2sigma absolute error
            Dt=10.^log10Dt;
            eDt=log(10)*10.^log10Dt.*elog10Dt; %2 sigma absolute error
            Title=[app.outputname, {'Dt', '2s'}];
            outputdata=num2cell([app.output,Dt,eDt]);

            filename=['Dt result ',datestr(now,'yyyy-mm-dd HH-MM-SS.FFF AM'), '.txt'];
            [FileName,PathName,~] = uiputfile({'*.txt';'*.csv';'*.xls';'*.xlsx'},'Save result',filename);
            if ~FileName
                return
            end
            [~,~,extension]=fileparts(FileName);
            switch extension
                case '.txt'
                    writecell([Title;outputdata],fullfile(PathName,FileName),"Delimiter",'tab');
                case '.csv'
                    writecell([Title;outputdata],fullfile(PathName,FileName));
                case {'.xlsx','.xls'}
                    if verLessThan('matlab','9.10')
                        xlswrite(fullfile(PathName,FileName),[Title;outputdata]);
                    else
                        writecell([Title;outputdata],fullfile(PathName,FileName),'AutoFitWidth', 0);
                    end
                otherwise
                    return
            end
        end

        % Button pushed function: CalculateDButton
        function CalculateDButtonPushed(app, event)
            global log10Dt elog10Dt
            t=app.TimeEditField.Value;
            %elog10Dt is 2sigma absolute error
            log10D=log10Dt-log10(t);
            elog10D=elog10Dt;
            D=10.^log10Dt/t;
            eD=log(10)*10.^log10Dt.*elog10Dt/t; %2 sigma absolute error
            Title=[app.outputname, {'log10(D)', '2s', 'D', '2s'}];
            tmp=[log10D,elog10D,D,eD];
            app.UITable4.Data=tmp;
            app.UITable4.ColumnFormat={'numeric'};
            outputdata=num2cell([app.output,tmp]);

            filename=['D result ',datestr(now,'yyyy-mm-dd HH-MM-SS.FFF AM'), '.txt'];
            [FileName,PathName,~] = uiputfile({'*.txt';'*.csv';'*.xls';'*.xlsx'},'Save result',filename);
            if ~FileName
                return
            end
            [~,~,extension]=fileparts(FileName);
            switch extension
                case '.txt'
                    writecell([Title;outputdata],fullfile(PathName,FileName),"Delimiter",'tab');
                case '.csv'
                    writecell([Title;outputdata],fullfile(PathName,FileName));
                case {'.xlsx','.xls'}
                    if verLessThan('matlab','9.10')
                        xlswrite(fullfile(PathName,FileName),[Title;outputdata]);
                    else
                        writecell([Title;outputdata],fullfile(PathName,FileName),'AutoFitWidth', 0);
                    end
                otherwise
                    return
            end
        end

        % Button pushed function: CalculatetButton
        function CalculatetButtonPushed(app, event)
            global log10Dt elog10Dt
            if isnan(log10Dt)
                msgbox('No fitting results can be found')
                return
            end
            T = app.InitialtemperatureT0EditField.Value; %initial temperature, K
            eT = app.TemperatureerrorEditField.Value; %initial temperature uncertainty, K
            ttrials = app.TrialsforMonteCarloEditField.Value;
            Dtype = app.UseArrheniusequationButton.Value;

            coolingpath = app.OptionsDropDown.Value;
            dT_dt = app.UITable3.Data{1}; %cooling rate
            rateunit=app.UITable3.Data{2};
            switch coolingpath
                case 'linear cooling'
                    switch rateunit
                        case 'K/myr'
                            dT_dt=dT_dt/1e6/365/24/3600;
                        case 'K/kyr'
                            dT_dt=dT_dt/1e3/365/24/3600;
                        case 'K/yr'
                            dT_dt=dT_dt/365/24/3600;
                        case 'K/sec'
                        otherwise
                            msgbox('Unknown unit of cooling constant')
                            return
                    end
                case 'exponential cooling'
                    switch rateunit
                        case 'K/myr'
                            dT_dt=dT_dt/1e6/365/24/3600;
                        case 'K/kyr'
                            dT_dt=dT_dt/1e3/365/24/3600;
                        case 'K/yr'
                            dT_dt=dT_dt/365/24/3600;
                        case 'K/sec'
                        otherwise
                            msgbox('Unknown unit of cooling constant')
                            return
                    end
                case 'parabolic cooling'
                    switch rateunit
                        case 'K/myr^2'
                            dT_dt=dT_dt/(1e6*365*24*3600)^2;
                        case 'K/kyr^2'
                            dT_dt=dT_dt/(1e3*365*24*3600)^2;
                        case 'K/yr^2'
                            dT_dt=dT_dt/(365*24*3600)^2;
                        case 'K/sec^2'
                        otherwise
                            msgbox('Unknown unit of cooling constant')
                            return
                    end
            end


            [t, et, isbreak]= solve_time_dependent_D(log10Dt, elog10Dt, T, eT, dT_dt, ttrials, app.UITable2.Data, coolingpath, Dtype, app);
            if isnan(t) || isbreak
                return
            end
            %define time unit
            if t(end,end)>(1e6*365*24*3600) %Myr
                t=t/(1e6*365*24*3600);
                tname='Timescale (Myr)';
            elseif t(end,end)>(1e3*365*24*3600) %kyr
                t=t/(1e3*365*24*3600);
                tname='Timescale (kyr)';
            elseif t(end,end)>(365*24*3600) %year
                t=t/(365*24*3600);
                tname='Timescale (year)';
            elseif t(end,end)>(24*3600) %day
                t=t/(24*3600);
                tname='Timescale (day)';
            elseif t(end,end)>3600 %hour
                t=t/3600;
                tname='Timescale (hour)';
            elseif t(end,end)>60 %minute
                t=t/60;
                tname='Timescale (minute)';
            else %second
                tname='Timescale (second)';
            end
            %plot timescales
            eb1=et(:,1).*t/100; %2 sigma absolute error
            eb2=et(:,2).*t/100;
            figure('Name', 'Timescales')
            errorbar(1:1:length(t), t, eb1, eb2, 'Marker','s', 'LineStyle', 'none');
            xlabel('Trials')
            ylabel(tname);
            xticks(0:1:(length(t)+1));
            xlim([0, length(t)+1]);
            startindex=regexp(tname, '(');
            endindex=regexp(tname, ')');
            unitname=tname(startindex+1:endindex-1);
            if length(t)>1
                title(['Timescale ranges from ', num2str(t(1), '%.f'), '_{', num2str(eb1(1), '%.f'), '}', '^{+', num2str(eb2(1), '%.f'), '} to ',...
                    num2str(t(end), '%.f'), '_{', num2str(eb1(end), '%.f'), '}', '^{', num2str(eb2(end), '%.f'), '} ', unitname]);
            else
                title(['Timescale = ', num2str(t, '%.f'), '_{', num2str(eb1, '%.f'), '}', '^{+', num2str(eb2, '%.f'), '} ', unitname]);
            end

            %write in file
            Title=[app.outputname,{tname, '-2s', '+2s'}];
            outputdata=num2cell([app.output, t, eb1,eb2]);

            filename=['t result ',datestr(now,'yyyy-mm-dd HH-MM-SS.FFF AM'), '.txt'];
            [FileName,PathName,~] = uiputfile({'*.txt';'*.csv';'*.xls';'*.xlsx'},'Save result',filename);
            if ~FileName
                return
            end
            [~,~,extension]=fileparts(FileName);
            switch extension
                case '.txt'
                    writecell([Title;outputdata],fullfile(PathName,FileName),"Delimiter",'tab');
                case '.csv'
                    writecell([Title;outputdata],fullfile(PathName,FileName));
                case {'.xlsx','.xls'}
                    if verLessThan('matlab','9.10')
                        xlswrite(fullfile(PathName,FileName),[Title;outputdata]);
                    else
                        writecell([Title;outputdata],fullfile(PathName,FileName),'AutoFitWidth', 0);
                    end 
                otherwise
                    return
            end
        end

        % Value changed function: 
        % AflatpeaktroughcanbeseeninprofileILCheckBox
        function AflatpeaktroughcanbeseeninprofileILCheckBoxValueChanged(app, event)
            value = app.AflatpeaktroughcanbeseeninprofileILCheckBox.Value;
            selectedButton = app.DiffusionprofileButtonGroup.SelectedObject;
            profiletype = selectedButton.Text;
            if ~value
                app.InputtheCrangeoftheinitialflatpeaktroughinprofileILLabel.Enable='on';
                app.minEditField.Enable='on';
                app.minEditFieldLabel.Enable='on';
                app.maxEditField.Enable='on';
                app.maxEditFieldLabel.Enable='on';
                app.steplengthEditField.Enable='on';
                app.steplengthEditFieldLabel.Enable='on';

                if contains(profiletype, {'I','K'}) %high value in the region
                    app.FixedC2CheckBox.Value=0;
                    app.FixedC2CheckBox.Enable='off';
                    app.C2EditField.Enable='off';
                else
                    app.FixedC1CheckBox.Value=0;
                    app.FixedC1CheckBox.Enable='off';
                    app.C1EditField.Enable='off';
                end
            else
                app.InputtheCrangeoftheinitialflatpeaktroughinprofileILLabel.Enable='off';
                app.minEditField.Enable='off';
                app.minEditFieldLabel.Enable='off';
                app.maxEditField.Enable='off';
                app.maxEditFieldLabel.Enable='off';
                app.steplengthEditField.Enable='off';
                app.steplengthEditFieldLabel.Enable='off';

                if contains(profiletype, {'I','K'}) %high value in the region
                    app.FixedC2CheckBox.Enable='on';
                    app.C2EditField.Enable='on';
                else
                    app.FixedC1CheckBox.Enable='on';
                    app.C1EditField.Enable='on';
                end
            end
        end

        % Button pushed function: SeeC1C2andC3definitionsButton
        function SeeC1C2andC3definitionsButtonPushed(app, event)
            str='Diffusion profile options.jpg';
            if exist(str, 'file')
                figure('Name', 'Diffusion profile options')
                imshow(str)
            else
                uialert(app.DiffuserGUI,"Cannot find the orginal file 'Diffusion profile options.jpg'",'')
                return
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if app.UseArrheniusequationButton.Value
                app.UITable2.ColumnName={'D0 (m2/s)','ΔH (kJ/mol)'};
                value = app.ExamplesDropDown.Value;
                site=find(strcmp(app.name,value));
                app.UITable2.Data=app.DiffCoef(site,:);
                app.ExamplesDropDown.Enable='on';
                app.ExamplesDropDownLabel.Enable='on';
            end
            if app.UseaconstantDButton.Value
                app.UITable2.ColumnName={'D (m2/s)','2σ D'};
                app.UITable2.Data=[2e-22,0];
                app.ExamplesDropDown.Enable='off';
                app.ExamplesDropDownLabel.Enable='off';
            end
        end

        % Selection changed function: BeamButtonGroup
        function BeamButtonGroupSelectionChanged(app, event)
            selectedButton = app.BeamButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Circular'
                    app.DiameterEditField.Enable='on';
                    app.DiameterEditFieldLabel.Enable='on';
                    app.WidthEditField.Enable='off';
                    app.WidthEditFieldLabel.Enable='off';
                    app.FWHMEditField.Enable='off';
                    app.FWHMEditFieldLabel.Enable='off';
                    app.FWHMGaussEditField.Enable='off';
                    app.FWHMGaussEditFieldLabel.Enable='off';
                    app.FWHMLorentzEditField.Enable='off';
                    app.FWHMLorentzEditFieldLabel.Enable='off';
                case 'Rectangle'
                    app.DiameterEditField.Enable='off';
                    app.DiameterEditFieldLabel.Enable='off';
                    app.WidthEditField.Enable='on';
                    app.WidthEditFieldLabel.Enable='on';
                    app.FWHMEditField.Enable='off';
                    app.FWHMEditFieldLabel.Enable='off';
                    app.FWHMGaussEditField.Enable='off';
                    app.FWHMGaussEditFieldLabel.Enable='off';
                    app.FWHMLorentzEditField.Enable='off';
                    app.FWHMLorentzEditFieldLabel.Enable='off';
                case {'Gauss','Lorentz'}
                    app.DiameterEditField.Enable='off';
                    app.DiameterEditFieldLabel.Enable='off';
                    app.WidthEditField.Enable='off';
                    app.WidthEditFieldLabel.Enable='off';
                    app.FWHMEditField.Enable='on';
                    app.FWHMEditFieldLabel.Enable='on';
                    app.FWHMGaussEditField.Enable='off';
                    app.FWHMGaussEditFieldLabel.Enable='off';
                    app.FWHMLorentzEditField.Enable='off';
                    app.FWHMLorentzEditFieldLabel.Enable='off';
                case 'Voigt'
                    app.DiameterEditField.Enable='off';
                    app.DiameterEditFieldLabel.Enable='off';
                    app.WidthEditField.Enable='off';
                    app.WidthEditFieldLabel.Enable='off';
                    app.FWHMEditField.Enable='off';
                    app.FWHMEditFieldLabel.Enable='off';
                    app.FWHMGaussEditField.Enable='on';
                    app.FWHMGaussEditFieldLabel.Enable='on';
                    app.FWHMLorentzEditField.Enable='on';
                    app.FWHMLorentzEditFieldLabel.Enable='on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiffuserGUI = uifigure('Visible', 'off');
            app.DiffuserGUI.Name = 'Diffuser';
            app.TabGroup = uitabgroup(app.DiffuserGUI);
            app.ImportdataTab = uitab(app.TabGroup);
            app.ImportdataTab.Title = '1. Import data';
            app.UIAxes = uiaxes(app.ImportdataTab);
            title(app.UIAxes, 'Diffusion data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'C')
            zlabel(app.UIAxes, 'Z')
            app.SelectfileButton = uibutton(app.ImportdataTab, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.CleartableButton = uibutton(app.ImportdataTab, 'push');
            app.CleartableButton.ButtonPushedFcn = createCallbackFcn(app, @CleartableButtonPushed, true);
            app.CleartableButton.Text = 'Clear table';
            app.UITable = uitable(app.ImportdataTab);
            app.UITable.ColumnName = {'Distance (X)'; 'Concentration (C)'; 'Uncertainty of C'};
            app.UITable.RowName = {};
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            app.ClipboardButton = uibutton(app.ImportdataTab, 'push');
            app.ClipboardButton.ButtonPushedFcn = createCallbackFcn(app, @ClipboardButtonPushed, true);
            app.ClipboardButton.Text = 'Clipboard';
            app.RandomButton = uibutton(app.ImportdataTab, 'push');
            app.RandomButton.ButtonPushedFcn = createCallbackFcn(app, @RandomButtonPushed, true);
            app.RandomButton.Text = 'Random';
            app.text11 = uilabel(app.ImportdataTab);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Diffuser';
            app.DeconvolutionoptionalTab = uitab(app.TabGroup);
            app.DeconvolutionoptionalTab.Title = 'Deconvolution (optional)';
            app.BeamButtonGroup = uibuttongroup(app.DeconvolutionoptionalTab);
            app.BeamButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BeamButtonGroupSelectionChanged, true);
            app.BeamButtonGroup.Title = 'Beam';
            app.CircularButton = uitogglebutton(app.BeamButtonGroup);
            app.CircularButton.Icon = 'circular.jpg';
            app.CircularButton.Text = 'Circular';
            app.CircularButton.Value = true;
            app.RectangleButton = uitogglebutton(app.BeamButtonGroup);
            app.RectangleButton.Icon = 'rectangle.jpg';
            app.RectangleButton.Text = 'Rectangle';
            app.GaussButton = uitogglebutton(app.BeamButtonGroup);
            app.GaussButton.Icon = 'gauss.jpg';
            app.GaussButton.Text = 'Gauss';
            app.LorentzButton = uitogglebutton(app.BeamButtonGroup);
            app.LorentzButton.Icon = 'lorentz.jpg';
            app.LorentzButton.Text = 'Lorentz';
            app.VoigtButton = uitogglebutton(app.BeamButtonGroup);
            app.VoigtButton.Icon = 'voigt.jpg';
            app.VoigtButton.Text = 'Voigt';
            app.DeconvoluteanalyticalbeameffectsCheckBox = uicheckbox(app.DeconvolutionoptionalTab);
            app.DeconvoluteanalyticalbeameffectsCheckBox.Text = 'Deconvolute analytical beam effects';
            app.DiameterEditFieldLabel = uilabel(app.DeconvolutionoptionalTab);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.DeconvolutionoptionalTab, 'numeric');
            app.WidthEditFieldLabel = uilabel(app.DeconvolutionoptionalTab);
            app.WidthEditFieldLabel.Enable = 'off';
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.DeconvolutionoptionalTab, 'numeric');
            app.WidthEditField.Enable = 'off';
            app.FWHMEditFieldLabel = uilabel(app.DeconvolutionoptionalTab);
            app.FWHMEditFieldLabel.Enable = 'off';
            app.FWHMEditFieldLabel.Text = 'FWHM';
            app.FWHMEditField = uieditfield(app.DeconvolutionoptionalTab, 'numeric');
            app.FWHMEditField.Enable = 'off';
            app.FWHMGaussEditFieldLabel = uilabel(app.DeconvolutionoptionalTab);
            app.FWHMGaussEditFieldLabel.Enable = 'off';
            app.FWHMGaussEditFieldLabel.Text = 'FWHM Gauss';
            app.FWHMGaussEditField = uieditfield(app.DeconvolutionoptionalTab, 'numeric');
            app.FWHMGaussEditField.Enable = 'off';
            app.fullwidthathalfmaximiumLabel = uilabel(app.DeconvolutionoptionalTab);
            app.fullwidthathalfmaximiumLabel.Text = 'full width at half maximium ';
            app.FWHMLorentzEditFieldLabel = uilabel(app.DeconvolutionoptionalTab);
            app.FWHMLorentzEditFieldLabel.Enable = 'off';
            app.FWHMLorentzEditFieldLabel.Text = 'FWHM Lorentz';
            app.FWHMLorentzEditField = uieditfield(app.DeconvolutionoptionalTab, 'numeric');
            app.FWHMLorentzEditField.Enable = 'off';
            app.BeamsizeunitDropDownLabel = uilabel(app.DeconvolutionoptionalTab);
            app.BeamsizeunitDropDownLabel.Text = 'Beam size unit';
            app.BeamsizeunitDropDown = uidropdown(app.DeconvolutionoptionalTab);
            app.BeamsizeunitDropDown.Items = {'nm', 'um', 'mm', 'm'};
            app.BeamsizeunitDropDown.Value = 'um';
            app.Hyperlink = uihyperlink(app.DeconvolutionoptionalTab);
            app.Hyperlink.URL = 'https://doi.org/10.1371/journal.pone.0241788';
            app.Hyperlink.Text = 'PACE (Jollands, 2020, PLOS ONE)';
            app.DeconvolutionismodifiedfromLabel = uilabel(app.DeconvolutionoptionalTab);
            app.DeconvolutionismodifiedfromLabel.Text = 'Deconvolution is modified from';
            app.DiffusionmodelingTab = uitab(app.TabGroup);
            app.DiffusionmodelingTab.Title = '2. Diffusion modeling';
            app.DiffusionprofileButtonGroup = uibuttongroup(app.DiffusionmodelingTab);
            app.DiffusionprofileButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DiffusionprofileButtonGroupSelectionChanged, true);
            app.DiffusionprofileButtonGroup.Title = 'Diffusion profile';
            app.AButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.AButton.Tag = 'A';
            app.AButton.Icon = 'A.jpg';
            app.AButton.Text = 'A';
            app.AButton.Value = true;
            app.BButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.BButton.Tag = 'B';
            app.BButton.Icon = 'B.jpg';
            app.BButton.Text = 'B';
            app.CButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.CButton.Tag = 'C';
            app.CButton.Icon = 'C.jpg';
            app.CButton.Text = 'C';
            app.DButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.DButton.Tag = 'D';
            app.DButton.Icon = 'D.jpg';
            app.DButton.Text = 'D';
            app.EButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.EButton.Tag = 'E';
            app.EButton.Icon = 'E.jpg';
            app.EButton.Text = 'E';
            app.FButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.FButton.Tag = 'F';
            app.FButton.Icon = 'F.jpg';
            app.FButton.Text = 'F';
            app.GButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.GButton.Tag = 'G';
            app.GButton.Icon = 'G.jpg';
            app.GButton.Text = 'G';
            app.HButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.HButton.Tag = 'H';
            app.HButton.Icon = 'H.jpg';
            app.HButton.Text = 'H';
            app.IButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.IButton.Tag = 'I';
            app.IButton.Icon = 'I.jpg';
            app.IButton.Text = 'I';
            app.JButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.JButton.Tag = 'J';
            app.JButton.Icon = 'J.jpg';
            app.JButton.Text = 'J';
            app.KButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.KButton.Tag = 'K';
            app.KButton.Icon = 'K.jpg';
            app.KButton.Text = 'K';
            app.LButton = uitogglebutton(app.DiffusionprofileButtonGroup);
            app.LButton.Tag = 'L';
            app.LButton.Icon = 'L.jpg';
            app.LButton.Text = 'L';
            app.InitialconcentrationsoptionalPanel = uipanel(app.DiffusionmodelingTab);
            app.InitialconcentrationsoptionalPanel.Title = 'Initial concentrations (optional)';
            app.minCLabel = uilabel(app.InitialconcentrationsoptionalPanel);
            app.minCLabel.Text = 'min C';
            app.maxCLabel = uilabel(app.InitialconcentrationsoptionalPanel);
            app.maxCLabel.Text = 'max C';
            app.mediumCusedinprofileKLLabel = uilabel(app.InitialconcentrationsoptionalPanel);
            app.mediumCusedinprofileKLLabel.Text = 'medium C used in profile K-L';
            app.FixedC1CheckBox = uicheckbox(app.InitialconcentrationsoptionalPanel);
            app.FixedC1CheckBox.Text = 'Fixed C1';
            app.AflatpeaktroughcanbeseeninprofileILCheckBox = uicheckbox(app.InitialconcentrationsoptionalPanel);
            app.AflatpeaktroughcanbeseeninprofileILCheckBox.ValueChangedFcn = createCallbackFcn(app, @AflatpeaktroughcanbeseeninprofileILCheckBoxValueChanged, true);
            app.AflatpeaktroughcanbeseeninprofileILCheckBox.Enable = 'off';
            app.AflatpeaktroughcanbeseeninprofileILCheckBox.Text = 'A flat peak/trough can be seen in profile I-L';
            app.AflatpeaktroughcanbeseeninprofileILCheckBox.Value = true;
            app.C1EditField = uieditfield(app.InitialconcentrationsoptionalPanel, 'numeric');
            app.C1EditField.ValueDisplayFormat = '%.2f';
            app.C2EditField = uieditfield(app.InitialconcentrationsoptionalPanel, 'numeric');
            app.C2EditField.ValueDisplayFormat = '%.2f';
            app.C2EditField.Value = 100;
            app.C3EditField = uieditfield(app.InitialconcentrationsoptionalPanel, 'numeric');
            app.C3EditField.ValueDisplayFormat = '%.2f';
            app.C3EditField.Enable = 'off';
            app.FixedC2CheckBox = uicheckbox(app.InitialconcentrationsoptionalPanel);
            app.FixedC2CheckBox.Text = 'Fixed C2';
            app.FixedC3CheckBox = uicheckbox(app.InitialconcentrationsoptionalPanel);
            app.FixedC3CheckBox.Text = 'Fixed C3';
            app.minEditFieldLabel = uilabel(app.InitialconcentrationsoptionalPanel);
            app.minEditFieldLabel.Enable = 'off';
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.InitialconcentrationsoptionalPanel, 'numeric');
            app.minEditField.ValueDisplayFormat = '%.2f';
            app.minEditField.Enable = 'off';
            app.maxEditFieldLabel = uilabel(app.InitialconcentrationsoptionalPanel);
            app.maxEditFieldLabel.Enable = 'off';
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.InitialconcentrationsoptionalPanel, 'numeric');
            app.maxEditField.ValueDisplayFormat = '%.2f';
            app.maxEditField.Enable = 'off';
            app.InputtheCrangeoftheinitialflatpeaktroughinprofileILLabel = uilabel(app.InitialconcentrationsoptionalPanel);
            app.InputtheCrangeoftheinitialflatpeaktroughinprofileILLabel.Enable = 'off';
            app.InputtheCrangeoftheinitialflatpeaktroughinprofileILLabel.Text = 'Input the C range of the initial flat peak/trough in profile I-L:';
            app.steplengthEditFieldLabel = uilabel(app.InitialconcentrationsoptionalPanel);
            app.steplengthEditFieldLabel.Enable = 'off';
            app.steplengthEditFieldLabel.Text = 'step length';
            app.steplengthEditField = uieditfield(app.InitialconcentrationsoptionalPanel, 'numeric');
            app.steplengthEditField.ValueDisplayFormat = '%.2f';
            app.steplengthEditField.Enable = 'off';
            app.SeeC1C2andC3definitionsButton = uibutton(app.InitialconcentrationsoptionalPanel, 'push');
            app.SeeC1C2andC3definitionsButton.ButtonPushedFcn = createCallbackFcn(app, @SeeC1C2andC3definitionsButtonPushed, true);
            app.SeeC1C2andC3definitionsButton.Text = 'See C1,C2,and C3 definitions';
            app.StartModelingButton = uibutton(app.DiffusionmodelingTab, 'push');
            app.StartModelingButton.ButtonPushedFcn = createCallbackFcn(app, @StartModelingButtonPushed, true);
            app.StartModelingButton.Text = 'Start Modeling';
            app.CalculateDtButton = uibutton(app.DiffusionmodelingTab, 'push');
            app.CalculateDtButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDtButtonPushed, true);
            app.CalculateDtButton.Text = 'Calculate Dt';
            app.XunitDropDownLabel = uilabel(app.DiffusionmodelingTab);
            app.XunitDropDownLabel.Text = 'X unit';
            app.XunitDropDown = uidropdown(app.DiffusionmodelingTab);
            app.XunitDropDown.Items = {'nm', 'um', 'mm', 'm'};
            app.XunitDropDown.Value = 'um';
            app.TimecalculationTab = uitab(app.TabGroup);
            app.TimecalculationTab.Title = '3. Time calculation';
            app.sigmaLabel = uilabel(app.TimecalculationTab);
            app.sigmaLabel.Text = '2 sigma';
            app.whencoolingLabel = uilabel(app.TimecalculationTab);
            app.whencoolingLabel.Enable = 'off';
            app.whencoolingLabel.Text = 'when cooling';
            app.KLabel = uilabel(app.TimecalculationTab);
            app.KLabel.Text = '(K)';
            app.CalculatetButton = uibutton(app.TimecalculationTab, 'push');
            app.CalculatetButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatetButtonPushed, true);
            app.CalculatetButton.Text = 'Calculate t';
            app.CoolingpathPanel = uipanel(app.TimecalculationTab);
            app.CoolingpathPanel.Title = 'Cooling path';
            app.OptionsDropDownLabel = uilabel(app.CoolingpathPanel);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.CoolingpathPanel);
            app.OptionsDropDown.Items = {'isothermal', 'linear cooling', 'exponential cooling', 'parabolic cooling'};
            app.OptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDownValueChanged, true);
            app.OptionsDropDown.Value = 'isothermal';
            app.UITable3 = uitable(app.CoolingpathPanel);
            app.UITable3.ColumnName = {'Constant (A)'; 'Unit'; 'Equation form'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = [true true false];
            app.DiffusionCoefficientDPanel = uipanel(app.TimecalculationTab);
            app.DiffusionCoefficientDPanel.Title = 'Diffusion Coefficient (D)';
            app.ExamplesDropDownLabel = uilabel(app.DiffusionCoefficientDPanel);
            app.ExamplesDropDownLabel.Text = 'Examples';
            app.ExamplesDropDown = uidropdown(app.DiffusionCoefficientDPanel);
            app.ExamplesDropDown.Items = {'Quartz Ti	Cherniak et al., 2007, Chem Geol', 'Option 2', 'Option 3', 'Option 4'};
            app.ExamplesDropDown.ValueChangedFcn = createCallbackFcn(app, @ExamplesDropDownValueChanged, true);
            app.ExamplesDropDown.Value = 'Quartz Ti	Cherniak et al., 2007, Chem Geol';
            app.UITable2 = uitable(app.DiffusionCoefficientDPanel);
            app.UITable2.ColumnName = {'D0 (m2/s)'; 'E (kJ/mol)'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.ButtonGroup = uibuttongroup(app.DiffusionCoefficientDPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.UseArrheniusequationButton = uiradiobutton(app.ButtonGroup);
            app.UseArrheniusequationButton.Text = 'Use Arrhenius equation: D=D0*exp(-E/RT)';
            app.UseArrheniusequationButton.Value = true;
            app.UseaconstantDButton = uiradiobutton(app.ButtonGroup);
            app.UseaconstantDButton.Text = 'Use a constant D';
            app.TemperatureerrorEditFieldLabel = uilabel(app.TimecalculationTab);
            app.TemperatureerrorEditFieldLabel.Text = 'Temperature error';
            app.TemperatureerrorEditField = uieditfield(app.TimecalculationTab, 'numeric');
            app.TemperatureerrorEditField.ValueDisplayFormat = '%.2f';
            app.TemperatureerrorEditField.Value = 20;
            app.TrialsforMonteCarloEditFieldLabel = uilabel(app.TimecalculationTab);
            app.TrialsforMonteCarloEditFieldLabel.Enable = 'off';
            app.TrialsforMonteCarloEditFieldLabel.Text = 'Trials for Monte Carlo';
            app.TrialsforMonteCarloEditField = uieditfield(app.TimecalculationTab, 'numeric');
            app.TrialsforMonteCarloEditField.ValueDisplayFormat = '%.0f';
            app.TrialsforMonteCarloEditField.Enable = 'off';
            app.TrialsforMonteCarloEditField.Value = 1000;
            app.InitialtemperatureT0EditFieldLabel = uilabel(app.TimecalculationTab);
            app.InitialtemperatureT0EditFieldLabel.Text = 'Initial temperature (T0)';
            app.InitialtemperatureT0EditField = uieditfield(app.TimecalculationTab, 'numeric');
            app.InitialtemperatureT0EditField.ValueDisplayFormat = '%.2f';
            app.InitialtemperatureT0EditField.Value = 1073;
            app.DcalculationTab = uitab(app.TabGroup);
            app.DcalculationTab.Title = 'D calculation';
            app.DcalculationforexperimentalstudyPanel = uipanel(app.DcalculationTab);
            app.DcalculationforexperimentalstudyPanel.Title = 'D calculation (for experimental study)';
            app.sLabel = uilabel(app.DcalculationforexperimentalstudyPanel);
            app.sLabel.Text = '(s)';
            app.TimeEditFieldLabel = uilabel(app.DcalculationforexperimentalstudyPanel);
            app.TimeEditFieldLabel.Text = ' Time';
            app.TimeEditField = uieditfield(app.DcalculationforexperimentalstudyPanel, 'numeric');
            app.TimeEditField.ValueDisplayFormat = '%.0f';
            app.TimeEditField.Value = 1000;
            app.CalculateDButton = uibutton(app.DcalculationforexperimentalstudyPanel, 'push');
            app.CalculateDButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDButtonPushed, true);
            app.CalculateDButton.Text = 'Calculate D';
            app.UITable4 = uitable(app.DcalculationTab);
            app.UITable4.ColumnName = {'log10(D)'; '2σ'; 'D'; '2σ'};
            app.UITable4.RowName = {};
            % Show the figure after all components are created
            app.DiffuserGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Diffuser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiffuserGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiffuserGUI)
        end
    end
end